classdef BarristaBotsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        LoadDobotMagicianButton  matlab.ui.control.Button
        plot                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % place your class properties here
        modelDobot
        qz = [0     0     0     0     0     0];
        qrDobot = [0 pi/4 pi/4 0 0];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            % Code to run once GUI is loaded successfully
            ax = gca;

            workspace = [-2 2 -2 2 -0.5 2];
            view(3)
            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % Turn off this load button and enable the move button
            app.LoadDobotMagicianButton.Enable = true;
            
        end

        % Button pushed function: LoadDobotMagicianButton
        function LoadDobotMagicianButtonPushed(app, event)
            DobotMagician
            app.modelDobot = DobotMagician;
            app.modelDobot.model.base = transl([1,0,0]);
            app.modelDobot.model.animate(app.qrDobot);
            plot(app.modelDobot,app.qrDobot,'workspace',[-2 2 -2 2 -0.5 2])
            hold on;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'BarristaBotsGUI';
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.Tag = 'axes1';
            app.LoadDobotMagicianButton = uibutton(app.figure1, 'push');
            app.LoadDobotMagicianButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDobotMagicianButtonPushed, true);
            app.LoadDobotMagicianButton.Text = 'Load Dobot Magician';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BarristaBotsGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end